classdef GUI_B_boundary < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        UIAxes2            matlab.ui.control.UIAxes
        StartButton        matlab.ui.control.Button
        NxSpinnerLabel     matlab.ui.control.Label
        NxSpinner          matlab.ui.control.Spinner
        NySpinnerLabel     matlab.ui.control.Label
        NySpinner          matlab.ui.control.Spinner
        NzSpinnerLabel     matlab.ui.control.Label
        NzSpinner          matlab.ui.control.Spinner
        SCTypeButtonGroup  matlab.ui.container.ButtonGroup
        Type1Button        matlab.ui.control.RadioButton
        Type2Button        matlab.ui.control.RadioButton
        BzSliderLabel      matlab.ui.control.Label
        BzSlider           matlab.ui.control.Slider
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            trapezoidal_test_GUI(app);
            app.Nx = app.NxSpinner.Value;
            app.Ny = app.NySpinner.Value;
            app.Nz = app.NzSpinner.Value;
            app.kappaIO = app.SCTypeButtonGroup.SelectedObject;

        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            P = get(app.UIAxes,'CurrentPoint');
            disp(P)
        end

        % Value changed function: BzSlider
        function BzSliderValueChanged(app, event)
            value = app.BzSlider.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Title'; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, {'Title'; ''})
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.NxSpinnerLabel = uilabel(app.UIFigure);
            app.NxSpinnerLabel.Text = 'Nx';
            app.NxSpinner = uispinner(app.UIFigure);
            app.NySpinnerLabel = uilabel(app.UIFigure);
            app.NySpinnerLabel.Text = 'Ny';
            app.NySpinner = uispinner(app.UIFigure);
            app.NzSpinnerLabel = uilabel(app.UIFigure);
            app.NzSpinnerLabel.Text = 'Nz';
            app.NzSpinner = uispinner(app.UIFigure);
            app.SCTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.SCTypeButtonGroup.Title = 'SC Type';
            app.Type1Button = uiradiobutton(app.SCTypeButtonGroup);
            app.Type1Button.Text = 'Type 1';
            app.Type1Button.Value = true;
            app.Type2Button = uiradiobutton(app.SCTypeButtonGroup);
            app.Type2Button.Text = 'Type 2';
            app.BzSliderLabel = uilabel(app.UIFigure);
            app.BzSliderLabel.Text = 'Bz';
            app.BzSlider = uislider(app.UIFigure);
            app.BzSlider.Limits = [0 10];
            app.BzSlider.ValueChangedFcn = createCallbackFcn(app, @BzSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_B_boundary

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end